classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Image          matlab.ui.control.Image
        RestartButton  matlab.ui.control.Button
        TextArea_2     matlab.ui.control.TextArea
        TextArea       matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main, score, name)
           app.CallingApp = main;  
            app.TextArea_2.Value = name;% + "won the game with " + string(score)+ " points";
%             app.CallingApp.game = Game();
%             app.CallingApp.dice = DiceArray();
%             app.CallingApp.updateTable();
            %app.CallingApp.updateTable2();
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            
            app.CallingApp.p1name = "p1";
            app.CallingApp.p2name = "p2"; 
            app.CallingApp.p1score = 0;
            app.CallingApp.p2score = 0;
            app.CallingApp.dice = DiceArray(); %
            app.CallingApp.updateTable();
            app.CallingApp.rolls = randi(6, 1, 6);
            app.CallingApp.updateDiceImages(app.rolls);
            app.CallingApp.validDice = [];
            app.CallingApp.numSelected = 0;
            app.CallingApp.turn = 1;
            app.CallingApp.Lamp.Enable = 'on';
            app.CallingApp.Lamp2.Enable = 'off';
            app.CallingApp.BankPointsButton.Enable = 'off';
            app.CallingApp.RollDiceButton.Enable = 'off';
            app.CallingApp.LocalButton.Enable = 'on';
            app.CallingApp.OnlineButton.Enable = 'on';
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'THE WINNER IS...'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'trophygif.gif');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end