classdef avatar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        OKButton                   matlab.ui.control.Button
        PickAvatarLabel            matlab.ui.control.Label
        SelectAvatarDropDown       matlab.ui.control.DropDown
        SelectAvatarDropDownLabel  matlab.ui.control.Label
        Image                      matlab.ui.control.Image
    end

    
    properties (Access = public)
        CallingApp % Description
        player
        imsource
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main, player)
            app.CallingApp = main;
            app.player = player;
        end

        % Value changed function: SelectAvatarDropDown
        function SelectAvatarDropDownValueChanged(app, event)
            value = app.SelectAvatarDropDown.Value;
            pathToMLAPP = fileparts(mfilename('fullpath'));
            switch (app.player)
                case (1)
                    switch (value)
                        case ('Monster')
                     app.imsource = fullfile(pathToMLAPP, 'images', 'avatars', 'p1', 'monster.png');

                        case ('Boy')
                            app.imsource = fullfile(pathToMLAPP, 'images', 'avatars', 'p1', 'boy.png');
                        
                        case ('Girl')
                            app.imsource = fullfile(pathToMLAPP, 'images', 'avatars', 'p1', 'girl.png');
                        case ('Cat')
                            app.imsource = fullfile(pathToMLAPP, 'images', 'avatars', 'p1', 'cat.png');
                    end
                case(-1)
                     switch (value)
                        case ('Monster')
                            app.imsource = fullfile(pathToMLAPP, 'images', 'avatars', 'p2', 'monster.png');
                            
                        case ('Boy')
                            app.imsource = fullfile(pathToMLAPP, 'images', 'avatars', 'p2', 'boy.png');
                        case ('Girl')
                            app.imsource = fullfile(pathToMLAPP, 'images', 'avatars', 'p2', 'girl.png');
                        case ('Cat')
                            app.imsource = fullfile(pathToMLAPP, 'images', 'avatars', 'p2', 'cat.png');                    
                     end
            end
            app.Image.ImageSource = app.imsource;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if (app.player == 1)
            app.CallingApp.Image3.ImageSource = app.imsource;
            else
                app.CallingApp.Image4.ImageSource = app.imsource;
            end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'avatars', 'monster.png');
            app.SelectAvatarDropDownLabel = uilabel(app.UIFigure);
            app.SelectAvatarDropDownLabel.Text = 'Select Avatar';
            app.SelectAvatarDropDown = uidropdown(app.UIFigure);
            app.SelectAvatarDropDown.Items = {'Monster', 'Boy', 'Girl', 'Cat'};
            app.SelectAvatarDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectAvatarDropDownValueChanged, true);
            app.SelectAvatarDropDown.Value = 'Monster';
            app.PickAvatarLabel = uilabel(app.UIFigure);
            app.PickAvatarLabel.Text = 'Pick Avatar';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = avatar(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end