classdef gameplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DebugLabel                     matlab.ui.control.Label
        PickyouravatarDropDown_2       matlab.ui.control.DropDown
        PickyouravatarDropDown_2Label  matlab.ui.control.Label
        PickyouravatarDropDown         matlab.ui.control.DropDown
        PickyouravatarDropDownLabel    matlab.ui.control.Label
        ScoreBreakdownLabel            matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        GameInfoLabel                  matlab.ui.control.Label
        Player1sturnLabel              matlab.ui.control.Label
        five                           matlab.ui.control.Image
        six                            matlab.ui.control.Image
        four                           matlab.ui.control.Image
        three                          matlab.ui.control.Image
        two                            matlab.ui.control.Image
        one                            matlab.ui.control.Image
        Player2nameEditField           matlab.ui.control.EditField
        Player2nameEditFieldLabel      matlab.ui.control.Label
        Player1nameEditField           matlab.ui.control.EditField
        Player1nameEditFieldLabel      matlab.ui.control.Label
        RolldiceButton                 matlab.ui.control.Button
        RestartButton                  matlab.ui.control.Button
        BankPointsButton               matlab.ui.control.Button
        RulesButton                    matlab.ui.control.Button
        EndmyturnButton                matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        OnlineButton                   matlab.ui.control.Button
        LocalButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        game      Game % Description
        dice 
        currentMeld
        rolls
        validDice
        triples
        numSelected
        turn

        p1name
        p2name
        p1score
        p2score
    end
    
    methods (Access = public)
       
%         function selectDice(app)
%             selected = app.dice.selectedIndices();
%             [score, triples, numFives, numOnes, hasMeld] = app.dice.generateMelds(app.dice.allValues());
%             app.validDice = [1 5 triples]; 
%             %isSelectionValid = all(ismember(app.dice.selectedValues(), validCombo));
% 
%             if (numel(selected) > 0 && isSelectionValid)
%                 app.DebugLabel.Text = char("Valid selection!"); % FIXME: need to proceed with score calculation
%             else
%                 app.DebugLabel.Text = char("Please select a valid combination of dice");
%             end
% 
%             numberofdiceSelected = numel(selected);
%             numberReq = 1; 
% 
%             if (numberofdiceSelected < numberReq)
%                 app.RolldiceButton.Enable = 'off';
%             elseif (numberofdiceSelected > 0 && isSelectionValid)
%                 app.RolldiceButton.Enable = 'on';
%             else
%                 app.RolldiceButton.Enable = 'off';
%             end
%         
%         end
        
        function app = updateTable(app)
            app.UITable.Data = [app.p1name app.p1score; app.p2name app.p2score];
        end

        function app = updateDiceImages(app, arr)
            app.one.ImageSource = string(fullfile('images','dice', char("dice" + arr(1) + ".png")));
            app.two.ImageSource = string(fullfile('images','dice', char("dice" + arr(2) + ".png")));
            app.three.ImageSource = string(fullfile('images','dice', char("dice" + arr(3) + ".png")));
            app.four.ImageSource = string(fullfile('images','dice', char("dice" + arr(4) + ".png")));
            app.five.ImageSource = string(fullfile('images','dice', char("dice" + arr(5) + ".png")));
            app.six.ImageSource = string(fullfile('images','dice', char("dice" + arr(6) + ".png")));

            % TO DO: Update selection background highlight.
            if (app.dice.isSelected(1)); app.one.BackgroundColor = 'r'; else; app.one.BackgroundColor = 'none'; end
            if (app.dice.isSelected(2)); app.two.BackgroundColor = 'r'; else; app.two.BackgroundColor = 'none'; end
            if (app.dice.isSelected(3)); app.three.BackgroundColor = 'r'; else; app.three.BackgroundColor = 'none'; end
            if (app.dice.isSelected(4)); app.four.BackgroundColor = 'r'; else; app.four.BackgroundColor = 'none'; end
            if (app.dice.isSelected(5)); app.five.BackgroundColor = 'r'; else; app.five.BackgroundColor = 'none'; end
            if (app.dice.isSelected(6)); app.six.BackgroundColor = 'r'; else; app.six.BackgroundColor = 'none'; end
        end
        
        function app = farkle(app)
            app.DebugLabel.Text = "farkle";
            app.changeTurn();
        end
        
        function app = changeTurn(app)
            app.turn = app.turn*-1;
            app.dice = app.dice.unselectAll();
            app.updateDiceImages();
            
        end
        
        function app = hotDice(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.p1name = "p1";
            app.p2name = "p2"; 
            app.p1score = 0;
            app.p2score = 0;
            app.dice = DiceArray();
            app.updateTable();
            app.rolls = randi(6, 1, 6);
            app.updateDiceImages(app.rolls);
            app.validDice = [];
            app.numSelected = 0;
            %app.turn = 1;

        end

        % Button pushed function: RulesButton
        function RulesButtonPushed(app, event)
            rules
        end

        % Button pushed function: RolldiceButton
        function RolldiceButtonPushed(app, event)

            %filepathAudio = fileparts(mfilename('ENG006FinalProject'));
            %[read_audio, speed_audio] = audioread(fullfile(filepathAudio, 'Audio','rolling_dice_audio.mp3'));
            %play_Audio = audioplayer(read_audio, speed_audio);
            %play(app.play_Audio);
            
            %app.selectDice();
            %app.RolldiceButton.Enable = 'off';
            % Important: unselected dice roll after one roll of all dice

            % Rolling the unselected dice:
            selected = app.dice.selectedIndices();
            app.DebugLabel.Text = num2str(selected);
            
            for i = 1:6
               if ~ismember(i, selected)
                   app.rolls(i) = randi(6);           
                end
                pause(0.1);
            end
            app.updateDiceImages(app.rolls);
            
            % Update the DiceArray
            app.dice = app.dice.updateValues(app.rolls);

            % Generate melds.   
            [score, app.triples, numFives, numOnes, hasMeld] = app.dice.generateMelds(app.dice.unselectedValues());
            app.validDice = [app.validDice, app.triples, 1, 5]; %Update validDice

            if app.turn == 1
                app.p1score = score;
            else
                app.p2score = score;
            end

            if score == 0
                app.farkle();
            end

            app.updateTable();
            app.RolldiceButton.Enable = 'off';
        end

        % Image clicked function: one
        function oneImageClicked(app, event)
            if (ismember(app.dice.getValue(1), app.validDice) && ~app.dice.isSelected(1))
                app.dice = app.dice.changeSelection(1);
                if (app.dice.isSelected(1)); app.one.BackgroundColor = 'r'; else; app.one.BackgroundColor = 'none'; end
                if (ismember(app.dice.getValue(1), app.triples))
                    if numel(find(app.dice.selectedValues() == app.dice.getValue(1))) == 3
                        app.RolldiceButton.Enable = 'on';
                    end
                else
                    app.RolldiceButton.Enable = 'on';
                end
            end
        end

        % Image clicked function: two
        function twoImageClicked(app, event)
            if (ismember(app.dice.getValue(2), app.validDice) && ~app.dice.isSelected(2))
            app.dice = app.dice.changeSelection(2);
            if (app.dice.isSelected(2)); app.two.BackgroundColor = 'r'; else; app.two.BackgroundColor = 'none'; end
                if (ismember(app.dice.getValue(2), app.triples))
                    if numel(find(app.dice.selectedValues() == app.dice.getValue(2))) == 3
                        app.RolldiceButton.Enable = 'on';
                    end
                else
                    app.RolldiceButton.Enable = 'on';
                end
            end
        end

        % Image clicked function: three
        function threeImageClicked(app, event)
            if (ismember(app.dice.getValue(3), app.validDice) && ~app.dice.isSelected(3))
            app.dice = app.dice.changeSelection(3);
            if (app.dice.isSelected(3)); app.three.BackgroundColor = 'r'; else; app.three.BackgroundColor = 'none'; end
                if (ismember(app.dice.getValue(3), app.triples))
                    if numel(find(app.dice.selectedValues() == app.dice.getValue(3))) == 3
                        app.RolldiceButton.Enable = 'on';
                    end
                else
                    app.RolldiceButton.Enable = 'on';
                end
            end
        end

        % Image clicked function: four
        function fourImageClicked(app, event)
            if (ismember(app.dice.getValue(4), app.validDice) && ~app.dice.isSelected(4))
            app.dice = app.dice.changeSelection(4);
            if (app.dice.isSelected(4)); app.four.BackgroundColor = 'r'; else; app.four.BackgroundColor = 'none'; end
                if (ismember(app.dice.getValue(4), app.triples))
                    if numel(find(app.dice.selectedValues() == app.dice.getValue(4))) == 3
                        app.RolldiceButton.Enable = 'on';
                    end
                else
                    app.RolldiceButton.Enable = 'on';
                end
            end
        end

        % Image clicked function: five
        function fiveImageClicked(app, event)
            if (ismember(app.dice.getValue(5), app.validDice) && ~app.dice.isSelected(5))
            app.dice = app.dice.changeSelection(5);
            if (app.dice.isSelected(5)); app.five.BackgroundColor = 'r'; else; app.five.BackgroundColor = 'none'; end
                if (ismember(app.dice.getValue(5), app.triples))
                    if numel(find(app.dice.selectedValues() == app.dice.getValue(5))) == 3
                        app.RolldiceButton.Enable = 'on';
                    end
                else
                    app.RolldiceButton.Enable = 'on';
                end
            end
        end

        % Image clicked function: six
        function sixImageClicked(app, event)
            if (ismember(app.dice.getValue(6), app.validDice) && ~app.dice.isSelected(6))
            app.dice = app.dice.changeSelection(6);
            if (app.dice.isSelected(6)); app.six.BackgroundColor = 'r'; else; app.six.BackgroundColor = 'none'; end
                if (ismember(app.dice.getValue(6), app.triples))
                    if numel(find(app.dice.selectedValues() == app.dice.getValue(6))) == 3
                        app.RolldiceButton.Enable = 'on';
                    end
                else
                    app.RolldiceButton.Enable = 'on';
                end
            end
        end

        % Button pushed function: BankPointsButton
        function BankPointsButtonPushed(app, event)
            % FIXME: need to update score
            %selected = app.dice.selectedValues(); 
            %[score, triples, numFives, numOnes, hasMeld] = app.dice.generateMelds(selected);
            %app.game.p1.score = app.game.p1.score + score;
            %app.updateTable();
            selected = app.dice.selectedValues();
            unselected = app.dice.unselectedValues();
            allDiceVals = app.dice.allValues();

            [sScore, sTriples, sNumFives, sNumOnes, sHasMeld] = app.dice.generateMelds(selected);
            [uScore, uTriples, uNumFives, uNumOnes, uHasMeld] = app.dice.generateMelds(unselected);
            % Update score ?? ..no .score?
            if(unselected >= selected)
                app.dice.score = app.dice.score + uScore;
                app.dice = DiceArray();
            else
                app.dice.Score = app.dice.Score +sScore;
                app.dice.updateValues(allDiceVals(~app.dice.selectedIndices()));
            end
            app.dice.updateValues();
            app.RolldiceButton.Enable = 'off';
        end

        % Drop down opening function: PickyouravatarDropDown
        function PickyouravatarDropDownOpening(app, event)
          
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            app.game = Game();
            app.dice = DiceArray();
            app.updateTable();
        end

        % Button pushed function: EndmyturnButton
        function EndmyturnButtonPushed(app, event)
            selected = app.dice.selectedValues();
            [score, triples, numFives, numOnes, hasMeld] = app.dice.generateMelds(selected);
            app.game.(app.game.currPlayer).score = score;
            app.game.changeTurn();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'prism');
            app.UIFigure.Name = 'MATLAB App';
            app.LocalButton = uibutton(app.UIFigure, 'push');
            app.LocalButton.Text = 'Local';
            app.OnlineButton = uibutton(app.UIFigure, 'push');
            app.OnlineButton.Text = 'Online';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Player Name'; 'Score'};
            app.UITable.RowName = {};
            app.EndmyturnButton = uibutton(app.UIFigure, 'push');
            app.EndmyturnButton.ButtonPushedFcn = createCallbackFcn(app, @EndmyturnButtonPushed, true);
            app.EndmyturnButton.Text = 'End my turn';
            app.RulesButton = uibutton(app.UIFigure, 'push');
            app.RulesButton.ButtonPushedFcn = createCallbackFcn(app, @RulesButtonPushed, true);
            app.RulesButton.Text = 'Rules';
            app.BankPointsButton = uibutton(app.UIFigure, 'push');
            app.BankPointsButton.ButtonPushedFcn = createCallbackFcn(app, @BankPointsButtonPushed, true);
            app.BankPointsButton.Text = 'Bank Points';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.RolldiceButton = uibutton(app.UIFigure, 'push');
            app.RolldiceButton.ButtonPushedFcn = createCallbackFcn(app, @RolldiceButtonPushed, true);
            app.RolldiceButton.Text = 'Roll dice';
            app.Player1nameEditFieldLabel = uilabel(app.UIFigure);
            app.Player1nameEditFieldLabel.Text = 'Player 1 name:';
            app.Player1nameEditField = uieditfield(app.UIFigure, 'text');
            app.Player2nameEditFieldLabel = uilabel(app.UIFigure);
            app.Player2nameEditFieldLabel.Text = 'Player 2 name:';
            app.Player2nameEditField = uieditfield(app.UIFigure, 'text');
            app.one = uiimage(app.UIFigure);
            app.one.ImageClickedFcn = createCallbackFcn(app, @oneImageClicked, true);
            app.one.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice1.png');
            app.two = uiimage(app.UIFigure);
            app.two.ImageClickedFcn = createCallbackFcn(app, @twoImageClicked, true);
            app.two.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice2.png');
            app.three = uiimage(app.UIFigure);
            app.three.ImageClickedFcn = createCallbackFcn(app, @threeImageClicked, true);
            app.three.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice3.png');
            app.four = uiimage(app.UIFigure);
            app.four.ImageClickedFcn = createCallbackFcn(app, @fourImageClicked, true);
            app.four.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice4.png');
            app.six = uiimage(app.UIFigure);
            app.six.ImageClickedFcn = createCallbackFcn(app, @sixImageClicked, true);
            app.six.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice6.png');
            app.five = uiimage(app.UIFigure);
            app.five.ImageClickedFcn = createCallbackFcn(app, @fiveImageClicked, true);
            app.five.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice5.png');
            app.Player1sturnLabel = uilabel(app.UIFigure);
            app.Player1sturnLabel.Text = 'Player 1''s turn';
            app.GameInfoLabel = uilabel(app.UIFigure);
            app.GameInfoLabel.Text = 'Game Info:';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'scoring.png');
            app.ScoreBreakdownLabel = uilabel(app.UIFigure);
            app.ScoreBreakdownLabel.Text = 'Score Breakdown';
            app.PickyouravatarDropDownLabel = uilabel(app.UIFigure);
            app.PickyouravatarDropDownLabel.Text = 'Pick your avatar';
            app.PickyouravatarDropDown = uidropdown(app.UIFigure);
            app.PickyouravatarDropDown.Items = {'Select', 'Cat', 'Dog', 'Girl', 'Boy'};
            app.PickyouravatarDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PickyouravatarDropDownOpening, true);
            app.PickyouravatarDropDown.Value = 'Cat';
            app.PickyouravatarDropDown_2Label = uilabel(app.UIFigure);
            app.PickyouravatarDropDown_2Label.Text = 'Pick your avatar';
            app.PickyouravatarDropDown_2 = uidropdown(app.UIFigure);
            app.PickyouravatarDropDown_2.Items = {'Select', 'Cat', 'Dog', 'Girl', 'Boy'};
            app.PickyouravatarDropDown_2.Value = 'Cat';
            app.DebugLabel = uilabel(app.UIFigure);
            app.DebugLabel.Text = 'Debug';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gameplay

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end