classdef players < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlayButton                     matlab.ui.control.Button
        Image_8                        matlab.ui.control.Image
        Image_7                        matlab.ui.control.Image
        Image_6                        matlab.ui.control.Image
        Image_5                        matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        Image_4                        matlab.ui.control.Image
        Image_3                        matlab.ui.control.Image
        Image_2                        matlab.ui.control.Image
        PickyouravatarLabel_2          matlab.ui.control.Label
        PickyouravatarLabel            matlab.ui.control.Label
        Player2enteryournameEditField  matlab.ui.control.EditField
        Player2enteryournameEditFieldLabel  matlab.ui.control.Label
        Player1enteryournameEditField  matlab.ui.control.EditField
        Player1enteryournameEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        player1Name % Description
        player2Name
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
           app.Image.BackgroundColor = 'r';
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            app.Image_2.BackgroundColor = 'r';
        end

        % Image clicked function: Image_3
        function Image_3Clicked(app, event)
            app.Image_3.BackgroundColor = 'r';
        end

        % Image clicked function: Image_4
        function Image_4Clicked(app, event)
            app.Image_4.BackgroundColor = 'r';
        end

        % Image clicked function: Image_5
        function Image_5Clicked(app, event)
            app.Image_5.BackgroundColor = 'r';
        end

        % Image clicked function: Image_6
        function Image_6Clicked(app, event)
            app.Image_6.BackgroundColor = 'r';
        end

        % Image clicked function: Image_7
        function Image_7Clicked(app, event)
            app.Image_7.BackgroundColor = 'r';
        end

        % Image clicked function: Image_8
        function Image_8Clicked(app, event)
            app.Image_8.BackgroundColor = 'r';
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.player1Name = string(app.Player1enteryournameEditField.Value);
            app.player2Name = string(app.Player2enteryournameEditField.Value);
            gameplay
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Player1enteryournameEditFieldLabel = uilabel(app.UIFigure);
            app.Player1enteryournameEditFieldLabel.Text = 'Player 1, enter your name: ';
            app.Player1enteryournameEditField = uieditfield(app.UIFigure, 'text');
            app.Player2enteryournameEditFieldLabel = uilabel(app.UIFigure);
            app.Player2enteryournameEditFieldLabel.Text = 'Player 2, enter your name: ';
            app.Player2enteryournameEditField = uieditfield(app.UIFigure, 'text');
            app.PickyouravatarLabel = uilabel(app.UIFigure);
            app.PickyouravatarLabel.Text = 'Pick your avatar: ';
            app.PickyouravatarLabel_2 = uilabel(app.UIFigure);
            app.PickyouravatarLabel_2.Text = 'Pick your avatar: ';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'images', 'avatars', 'girl.png');
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageClickedFcn = createCallbackFcn(app, @Image_3Clicked, true);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'images', 'avatars', 'boy.png');
            app.Image_4 = uiimage(app.UIFigure);
            app.Image_4.ImageClickedFcn = createCallbackFcn(app, @Image_4Clicked, true);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'images', 'avatars', 'monster.png');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'avatars', 'cat.png');
            app.Image_5 = uiimage(app.UIFigure);
            app.Image_5.ImageClickedFcn = createCallbackFcn(app, @Image_5Clicked, true);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'images', 'avatars', 'cat.png');
            app.Image_6 = uiimage(app.UIFigure);
            app.Image_6.ImageClickedFcn = createCallbackFcn(app, @Image_6Clicked, true);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'images', 'avatars', 'girl.png');
            app.Image_7 = uiimage(app.UIFigure);
            app.Image_7.ImageClickedFcn = createCallbackFcn(app, @Image_7Clicked, true);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'images', 'avatars', 'boy.png');
            app.Image_8 = uiimage(app.UIFigure);
            app.Image_8.ImageClickedFcn = createCallbackFcn(app, @Image_8Clicked, true);
            app.Image_8.ImageSource = fullfile(pathToMLAPP, 'images', 'avatars', 'monster.png');
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = players

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end